classdef DialogBoxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogBoxUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CalculateButton               matlab.ui.control.Button
        DialogBoxLabel                matlab.ui.control.Label
        PositiveCoefficientEditField  matlab.ui.control.NumericEditField
        PositiveCoefficientLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        MainAppCaller % handle to Main caller app
        maxV %Max Value
    end
    
    %Helper Functions
    methods (Access = private)
        %Function to estimate if the insert coefficient value is valid
        function validParam = isParamValid(app,maxvalue,coeff)
            if coeff>maxvalue
                %Alert message
                msg = "Please be aware that the provided value is too large: " + num2str(coeff);
                title = "Coefficient greater than " + num2str(maxvalue);
                uialert(app.DialogBoxUIFigure,msg,title,'Icon','warning');
                % In case the coefficient is larger than the maximum value,
                % the function returns false, and a new coefficient needs 
                % to be added
                validParam = false;

            elseif coeff<=0
                %Alert message
                msg = "Please provide a numerical positive coefficient!";
                title = "Negative Coefficient";
                uialert(app.DialogBoxUIFigure,msg,title,'Icon','warning');
                % In case the coefficient negative, the function returns 
                % false, and a new coefficient needs to be added
                validParam = false;

            else
                %The coefficient value is smaller than the maximum value,
                %the result of the function is set to true
                validParam = true;
            end
        end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, maxvalue)
            %From the main app
            app.MainAppCaller = caller;
            app.maxV = maxvalue;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %Check if the insert coefficient is valid
            if isParamValid(app,app.maxV,app.PositiveCoefficientEditField.Value)
                %Call of the ParameterEstimator function in the Main App
                ParameterEstimator(app.MainAppCaller, app.PositiveCoefficientEditField.Value);
                
                % Delete the Dialog Box
                DialogBoxUIFigureCloseRequest(app)
            end
        end

        % Close request function: DialogBoxUIFigure
        function DialogBoxUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogBoxUIFigure = uifigure('Visible', 'off');
            app.DialogBoxUIFigure.Name = 'Dialog Box';
            app.DialogBoxUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogBoxUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.DialogBoxUIFigure);
            app.PositiveCoefficientLabel = uilabel(app.GridLayout);
            app.PositiveCoefficientLabel.Text = {'Positive'; 'Coefficient'};
            app.PositiveCoefficientEditField = uieditfield(app.GridLayout, 'numeric');
            app.PositiveCoefficientEditField.UpperLimitInclusive = 'off';
            app.DialogBoxLabel = uilabel(app.GridLayout);
            app.DialogBoxLabel.Text = 'Dialog Box';
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.DialogBoxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBoxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogBoxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogBoxUIFigure)
        end
    end
end