classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure             matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        EstimateButton           matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        ParameterEditField       matlab.ui.control.NumericEditField
        ParameterEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        DialogApp  % Dialog box app
        maxvalue = 25; %Maximum value set to 25
    end
    
    methods (Access = public)
        % Function to estimate the parameter after receiving the coefficient
        % from the dialog box app
        function ParameterEstimator(app,coeff)
            app.ParameterEditField.Value = (coeff^0.1)*10;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EstimateButton
        function EstimateButtonPushed(app, event)
            % Disable the Estimate button after pressing it
             app.EstimateButton.Enable = 'off';

            % Call of the Dialog Box
             app.DialogApp = DialogBoxApp(app,app.maxvalue);

            % Enable again the Estimate button after closing the Dialog App
              waitfor(app.DialogApp);

            % Check if the Main app is still open, this "if" prevent an error
            % when the main app is closed!
              if isvalid(app)
                  app.EstimateButton.Enable = 'on';
              end
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            % Close app request
            msg = "Do you want to close the app?";
            title = "App Close request";
            closeReq = uiconfirm(app.MainUIFigure,msg,title);

            % Close the Main app and the Dialog Box
            switch closeReq
                case 'OK' % In case the user press the "OK" button
                    delete(app.DialogApp)
                    delete(app)
                case 'Cancel' % In case the user press the "Cancel" button
                    return % Noting happens!
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main App';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MainUIFigure);
            app.ParameterEditFieldLabel = uilabel(app.GridLayout);
            app.ParameterEditFieldLabel.Text = 'Parameter';
            app.ParameterEditField = uieditfield(app.GridLayout, 'numeric');
            app.ParameterEditField.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img.jpg');
            app.EstimateButton = uibutton(app.GridLayout, 'push');
            app.EstimateButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateButtonPushed, true);
            app.EstimateButton.Text = 'Estimate';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end